classdef RobotSimulatorApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        ShowVelocitiesCheckBox          matlab.ui.control.CheckBox
        ConstVelocitiesSimButton        matlab.ui.control.Button
        LineStartingPointTextArea       matlab.ui.control.TextArea
        LineStartingPointTextAreaLabel  matlab.ui.control.Label
        LineFinalPointTextArea          matlab.ui.control.TextArea
        LineFinalPointTextAreaLabel     matlab.ui.control.Label
        JointsAnglesandVelocitiesPanel  matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        UIAxes10_6                      matlab.ui.control.UIAxes
        UIAxes10_5                      matlab.ui.control.UIAxes
        UIAxes10_4                      matlab.ui.control.UIAxes
        UIAxes10_3                      matlab.ui.control.UIAxes
        UIAxes10_2                      matlab.ui.control.UIAxes
        UIAxes10                        matlab.ui.control.UIAxes
        SmoothJointAnglesSimButton      matlab.ui.control.Button
        UIAxes11                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        robot = RobotArm(15, 20, 30, 30, 1e-4, 2);
    end
    
    methods (Access = private)
        
        function plotJointAnglesInfo(app, axesArray)
            rob = app.robot;
            for ii = 0:5
                if ii < 3
                    plot(axesArray(ii+1), rob.t, rob.q(mod(ii, 3)+1, :));
                else
                    plot(axesArray(ii+1), rob.t, rob.q_1(mod(ii, 3)+1, :));
                end
            end
        end
        
        function buttonPushed(app, trajectoryType)
            % TODO: Fix the traj created when pa.z != pb.z
            PA = cell2mat(textscan(app.LineStartingPointTextArea.Value{1}, '%f', 'Delimiter', ','));
            PB = cell2mat(textscan(app.LineFinalPointTextArea.Value{1}, '%f', 'Delimiter', ','));

            app.robot.generateTrajectory(PA, PB);
            try 
                if strcmp(trajectoryType, 'const Velocity')
                    app.robot.performSimpleInverseKinematics;
                else
                    app.robot.smoothJointAnglesInverseKinematics;
                end
            catch
                % This block executes if generated trajectory is outside of
                % robot's reach
                disp('Invalid Values')
                uialert(app.UIFigure, "The trajectory generated by Starting " + ...
                    "and Finish Points is outside of robot's reach", 'Error', 'Icon', 'error');
                return
            end


            jointStateAxesArray = [app.UIAxes10, app.UIAxes10_2, app.UIAxes10_3, ...
                app.UIAxes10_4, app.UIAxes10_5, app.UIAxes10_6];

            app.plotJointAnglesInfo(jointStateAxesArray);

            app.robot.animateRobot(app.UIAxes11, app.ShowVelocitiesCheckBox.Value);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ConstVelocitiesSimButton
        function ConstVelocitiesSimButtonPushed(app, event)
            app.buttonPushed('const Velocity');
        end

        % Button pushed function: SmoothJointAnglesSimButton
        function SmoothJointAnglesSimButtonPushed(app, event)
            app.buttonPushed('')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes11 = uiaxes(app.GridLayout);
            title(app.UIAxes11, 'Robot Movement')
            xlabel(app.UIAxes11, 'X')
            ylabel(app.UIAxes11, 'Y')
            zlabel(app.UIAxes11, 'Z')
            app.SmoothJointAnglesSimButton = uibutton(app.GridLayout, 'push');
            app.SmoothJointAnglesSimButton.ButtonPushedFcn = createCallbackFcn(app, @SmoothJointAnglesSimButtonPushed, true);
            app.SmoothJointAnglesSimButton.Text = 'Smooth Joint Angles Sim';
            app.JointsAnglesandVelocitiesPanel = uipanel(app.GridLayout);
            app.JointsAnglesandVelocitiesPanel.Title = 'Joints Angles and Velocities';
            app.GridLayout2 = uigridlayout(app.JointsAnglesandVelocitiesPanel);
            app.UIAxes10 = uiaxes(app.GridLayout2);
            title(app.UIAxes10, 'q1 Angle')
            xlabel(app.UIAxes10, 'Time [s]')
            ylabel(app.UIAxes10, 'Angle [rad]')
            app.UIAxes10_2 = uiaxes(app.GridLayout2);
            title(app.UIAxes10_2, 'q2 Angle')
            xlabel(app.UIAxes10_2, 'Time [s]')
            ylabel(app.UIAxes10_2, 'Angle [rad]')
            app.UIAxes10_3 = uiaxes(app.GridLayout2);
            title(app.UIAxes10_3, 'q3 Angle')
            xlabel(app.UIAxes10_3, 'Time [s]')
            ylabel(app.UIAxes10_3, 'Angle [rad]')
            app.UIAxes10_4 = uiaxes(app.GridLayout2);
            title(app.UIAxes10_4, 'q2 Velocity')
            xlabel(app.UIAxes10_4, 'Time [s]')
            ylabel(app.UIAxes10_4, 'Vel [rad/s]')
            app.UIAxes10_5 = uiaxes(app.GridLayout2);
            title(app.UIAxes10_5, 'q2 Velocity')
            xlabel(app.UIAxes10_5, 'Time [s]')
            ylabel(app.UIAxes10_5, 'Vel [rad/s]')
            app.UIAxes10_6 = uiaxes(app.GridLayout2);
            title(app.UIAxes10_6, 'q3 Velocity')
            xlabel(app.UIAxes10_6, 'Time [s]')
            ylabel(app.UIAxes10_6, 'Vel [rad/s]')
            app.LineFinalPointTextAreaLabel = uilabel(app.GridLayout);
            app.LineFinalPointTextAreaLabel.Text = 'Line Final Point';
            app.LineFinalPointTextArea = uitextarea(app.GridLayout);
            app.LineFinalPointTextArea.Placeholder = 'X, Y, Z';
            app.LineFinalPointTextArea.Value = {'20, -30, 60'};
            app.LineStartingPointTextAreaLabel = uilabel(app.GridLayout);
            app.LineStartingPointTextAreaLabel.Text = 'Line Starting Point';
            app.LineStartingPointTextArea = uitextarea(app.GridLayout);
            app.LineStartingPointTextArea.Placeholder = 'X, Y, Z';
            app.LineStartingPointTextArea.Value = {'20, -30, 45'};
            app.ConstVelocitiesSimButton = uibutton(app.GridLayout, 'push');
            app.ConstVelocitiesSimButton.ButtonPushedFcn = createCallbackFcn(app, @ConstVelocitiesSimButtonPushed, true);
            app.ConstVelocitiesSimButton.Text = 'Const Velocities Sim';
            app.ShowVelocitiesCheckBox = uicheckbox(app.GridLayout);
            app.ShowVelocitiesCheckBox.Text = 'Show Velocities';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RobotSimulatorApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end